classdef inicio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Panel                matlab.ui.container.Panel
        StartExerciseButton  matlab.ui.control.Button
        SeeMyHistoryButton   matlab.ui.control.Button
        Image                matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SeeMyHistoryButton
        function SeeMyHistoryButtonPushed(app, event)
            historyWindow = historico;
            historyFig = historyWindow.UIFigure; 

            % Design the interface for the history window

            app.UIFigure.UserData.historyFig = historyFig;

            % Hide the main window
            app.UIFigure.Visible = 'off';
        end

        % Button pushed function: StartExerciseButton
        function StartExerciseButtonPushed(app, event)
            exerciseWindow = video;
            exerciseFig = exerciseWindow.UIFigure; 

            % Design the interface for the history window

            app.UIFigure.UserData.exerciseFig = exerciseFig;

            % Hide the main window
            app.UIFigure.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo1.png');
            app.Panel = uipanel(app.UIFigure);
            app.SeeMyHistoryButton = uibutton(app.Panel, 'push');
            app.SeeMyHistoryButton.ButtonPushedFcn = createCallbackFcn(app, @SeeMyHistoryButtonPushed, true);
            app.SeeMyHistoryButton.Text = 'See My History';
            app.StartExerciseButton = uibutton(app.Panel, 'push');
            app.StartExerciseButton.ButtonPushedFcn = createCallbackFcn(app, @StartExerciseButtonPushed, true);
            app.StartExerciseButton.Text = 'Start Exercise';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = inicio

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end