classdef progress < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        InitialPageButton       matlab.ui.control.Button
        Image                   matlab.ui.control.Image
        CheckyourprogressLabel  matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load the running time and the epoch scores to plot
            runtime = evalin('base', 'runtime');
            y = evalin('base', 'scores'); 
            x = linspace(1,runtime/60,length(y));
            plot(app.UIAxes, x, y);
            ylim(app.UIAxes,[0 100])
            
        end

        % Button pushed function: InitialPageButton
        function InitialPageButtonPushed(app, event)
            inicioWindow = inicio;
            inicioFig = inicioWindow.UIFigure; 

            app.UIFigure.UserData.inicioFig = inicioFig;

            % Hide the main window
            app.UIFigure.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Meditation Time')
            ylabel(app.UIAxes, 'Meditation Level')
            zlabel(app.UIAxes, 'Z')
            app.CheckyourprogressLabel = uilabel(app.UIFigure);
            app.CheckyourprogressLabel.Text = 'Check your progress!';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo2.png');
            app.InitialPageButton = uibutton(app.UIFigure, 'push');
            app.InitialPageButton.ButtonPushedFcn = createCallbackFcn(app, @InitialPageButtonPushed, true);
            app.InitialPageButton.Text = 'Initial Page';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = progress

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end