classdef score < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image                           matlab.ui.control.Image
        ProgressAlongtheExerciseButton  matlab.ui.control.Button
        InitialPageButton               matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        CheckyourscoreLabel             matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load the stored score and show it to the user
            val = evalin('base', 'val'); 
            app.Label.Text = num2str(val);
        end

        % Button pushed function: InitialPageButton
        function InitialPageButtonPushed(app, event)
            inicioWindow = inicio;
            inicioFig = inicioWindow.UIFigure; 

            app.UIFigure.UserData.inicioFig = inicioFig;

            % Hide the main window
            app.UIFigure.Visible = 'off';
        end

        % Button pushed function: ProgressAlongtheExerciseButton
        function ProgressAlongtheExerciseButtonPushed(app, event)
            progressWindow = progress;
            progressFig = progressWindow.UIFigure; 

            app.UIFigure.UserData.progressFig = progressFig;

            % Hide the main window
            app.UIFigure.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CheckyourscoreLabel = uilabel(app.UIFigure);
            app.CheckyourscoreLabel.Text = 'Check your score!';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'green-glowing-circle-icon-13.png');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0';
            app.InitialPageButton = uibutton(app.UIFigure, 'push');
            app.InitialPageButton.ButtonPushedFcn = createCallbackFcn(app, @InitialPageButtonPushed, true);
            app.InitialPageButton.Text = 'Initial Page';
            app.ProgressAlongtheExerciseButton = uibutton(app.UIFigure, 'push');
            app.ProgressAlongtheExerciseButton.ButtonPushedFcn = createCallbackFcn(app, @ProgressAlongtheExerciseButtonPushed, true);
            app.ProgressAlongtheExerciseButton.Text = 'Progress Along the Exercise';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo2.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = score

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end