classdef video < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        BackButton  matlab.ui.control.Button
        UIAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        AudioPlayer audioplayer
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            addpath 'D:\Neuro\Projeto\MindSpark\functions\sigprocfunc'
            addpath 'D:\Neuro\Projeto\MindSpark'
            %global cont;
            %cont = 1;
            mu = MuseUdp();
            
            epoch_length = 30;
            Fs = 256;
            
            vector_tp9 = [];
            vector_af7 = [];
            vector_af8 = [];
            vector_tp10 = [];
            
            EalphaGamma_tp = [];
            EalphaBetaASM_tp = [];
            EEbeta_tp = [];
            EEgamma_tp = [];
            EEtheta_tp = [];
            DASMalpha_tp = [];
            PSD_alpha_tp = [];
            
            EalphaGamma_af = [];
            EalphaBetaASM_af = [];
            EEbeta_af = [];
            EEgamma_af = [];
            EEtheta_af = [];
            DASMalpha_af = [];
            PSD_alpha_af = [];
            
            alpha_freq = [8, 13];       
            theta_freq = [4, 8];
            beta_freq = [14, 30];
            gamma_freq = [30; 50];
            
            vid = VideoReader('video_original.mp4');
            axes_handle = app.UIAxes;
            
            runtime = 120;

            % Preload frames
            numFramesToPreload = vid.FrameRate*runtime;
            assignin('base', 'numFramesToPreload', numFramesToPreload);
            frames = cell(1, numFramesToPreload);
            for i = 1:numFramesToPreload
                frames{i} = readFrame(vid);
            end
%             
%             % Display frames
%             for i = 1:numFramesToPreload
%                 imshow(frames{i}, 'Parent', axes_handle);
%                 drawnow;
%             end
            i = 1;
            audio_player = audioplayer(audioread('teste.mp3'), 44100);
            play(audio_player);
            tic
            while hasFrame(vid) && toc<runtime && i<numFramesToPreload
                imshow(frames{i}, 'Parent', axes_handle);
                i = i+1;
                drawnow;


                [data, timestamp, success] = mu.get_eeg_sample();
                if success
                    vector_tp9(end+1) = data(1)-data(5);
                    vector_af7(end+1) = data(2)-data(5);
                    vector_af8(end+1) = data(3)-data(5);
                    vector_tp10(end+1) = data(4)-data(5);
                end
            end
           
            assignin('base', 'vector_tp9', vector_tp9);
            assignin('base', 'vector_tp10', vector_tp10);
            assignin('base', 'vector_af7', vector_af7);
            assignin('base', 'vector_af8', vector_af8);
%             while strcmp(f.State, 'running')
%                 pause(0.1)
%             end
%             
            %val = fetchOutputs(f);
            %val = 0;

            signal_tp = (vector_tp9 + vector_tp10) ./2;
            signal_af = (vector_af8 + vector_af7) ./2;
            
            [alpha_signaltp, theta_signaltp, beta_signaltp, gamma_signaltp] = signal_decompose(signal_tp, Fs);
            [alpha_signalaf, theta_signalaf, beta_signalaf, gamma_signalaf] = signal_decompose(signal_af, Fs);
            
            assignin('base', 'alpha_signaltp', alpha_signaltp);

            EalphaGamma_af = (signal_energy(alpha_signalaf, epoch_length, Fs)./signal_energy(gamma_signalaf, epoch_length, Fs));
            EalphaBetaASM_af = (signal_energy(alpha_signalaf, epoch_length, Fs)./asm_signal(vector_af7,vector_af8,epoch_length, Fs));
            EEbeta_af = (energy_entropy(beta_signalaf, epoch_length, Fs));
            EEgamma_af = (energy_entropy(gamma_signalaf, epoch_length, Fs));
            EEtheta_af = (energy_entropy(theta_signalaf, epoch_length,Fs));
            DASMalpha_af = (dasm_signal(vector_af7, vector_af8, epoch_length, Fs));
            PSD_beta_af = (psd_signal(beta_signalaf, epoch_length, Fs, alpha_freq));
            
            EalphaGamma_tp = (signal_energy(alpha_signaltp, epoch_length, Fs)./signal_energy(gamma_signaltp, epoch_length, Fs));
            EalphaBetaASM_tp = (signal_energy(alpha_signaltp, epoch_length, Fs)./asm_signal(vector_tp9,vector_tp10,epoch_length, Fs));
            EEbeta_tp = (energy_entropy(beta_signaltp, epoch_length, Fs));
            EEgamma_tp = (energy_entropy(gamma_signaltp, epoch_length, Fs));
            EEtheta_tp = (energy_entropy(theta_signaltp, epoch_length,Fs));
            DASMalpha_tp = (dasm_signal(vector_tp9, vector_tp10, epoch_length, Fs));
            PSD_beta_tp = (psd_signal(beta_signaltp, epoch_length, Fs, alpha_freq));
            

            assignin('base', 'EalphaGamma_af', EalphaGamma_af);
            assignin('base', 'EalphaBetaASM_af', EalphaBetaASM_af);
            assignin('base', 'EEbeta_af', EEbeta_af);
            assignin('base', 'EEgamma_af', EEgamma_af);
            assignin('base', 'EEtheta_af', EEtheta_af);
            assignin('base', 'DASMalpha_af', DASMalpha_af);
            assignin('base', 'PSD_beta_af', PSD_beta_af);
            
            %datasetFolder = {pwd, "creating dataset"};
            modelFile = "D:\Neuro\Projeto\MindSpark\creating dataset\bestModelBoth.mat";
            load(modelFile,'bestClassifier','mu','sigma');
            
            features = [EalphaGamma_af' EalphaBetaASM_af' EEbeta_af' EEgamma_af' EEtheta_af' DASMalpha_af' PSD_beta_af' EalphaGamma_tp' EalphaBetaASM_tp' EEbeta_tp' EEgamma_tp' EEtheta_tp' DASMalpha_tp' PSD_beta_tp'];
            features = (features-mu)./sigma;
            [~, scores, ~] = predict(bestClassifier, features);

            assignin('base', 'scores', scores);
            % predictedY- 1 if stressed, 0 if normal
            % scores - analog to class probability
            
            %     for i=1:length(predictedY)
            %         str="";
            %         if predictedY(i)==1
            %             str = str + "Stress: Level " + int2str(scores(i)*5);
            %         else
            %             str = str + "Calm : Level " + int2str(scores(i)*5);
            %         end
            %     end
            
            %global sc;

            val = max(scores(:,1)) * 100;

            assignin('base', 'val', val);

            stop(audio_player);
            delete(audio_player)

            scoreWindow = score;
            scoreFig = scoreWindow.UIFigure; 

            app.UIFigure.UserData.scoreFig = scoreFig;

            % Hide the main window
            app.UIFigure.Visible = 'off';

        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            inicioWindow = inicio;
            inicioFig = inicioWindow.UIFigure; 

            app.UIFigure.UserData.inicioFig = inicioFig;

            % Hide the main window
            app.UIFigure.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = video

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end